classdef app6 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        VerificationOfTheveninsTheoremLabel  matlab.ui.control.Label
        TextArea            matlab.ui.control.TextArea
        TheoryButton        matlab.ui.control.Button
        ProcedureButton     matlab.ui.control.Button
        SimulatorButton     matlab.ui.control.Button
        QuestionsButton     matlab.ui.control.Button
        VideoLectureButton  matlab.ui.control.Button
        ReferencesButton    matlab.ui.control.Button
        OBJECTIVELabel      matlab.ui.control.Label
        TextArea_2          matlab.ui.control.TextArea
        ObjectiveButton     matlab.ui.control.Button
        ApparatusButton     matlab.ui.control.Button
        TextArea_3          matlab.ui.control.TextArea
        APPARATUSLabel      matlab.ui.control.Label
        UITable             matlab.ui.control.Table
        TextArea_4          matlab.ui.control.TextArea
        THEORYLabel         matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UITable.Data = {'1', 'Rheostat', '720Ω,1.2A', '5';'','','50Ω,5A','';'','','142Ω,28A','';'2', 'Ammeter', '0-2A(AC)', '1';'3', 'Voltmeter', '0-300V(AC)', '1';'4', 'Single Phase Dimmer state', '0-270V,10A', '1';'5', 'Two-way switch (DPST)', '', '1'};
        end

        % Button pushed function: ObjectiveButton
        function ObjectiveButtonPushed(app, event)
            app.TextArea_2.Visible='on';
            app.OBJECTIVELabel.Visible='on';
            app.TextArea_3.Visible='off';
            app.APPARATUSLabel.Visible='off';
            app.UITable.Visible='off';
            app.THEORYLabel.Visible='off';
            app.TextArea_4.Visible='off';
        end

        % Button pushed function: ApparatusButton
        function ApparatusButtonPushed(app, event)
            app.TextArea_2.Visible='off';
            app.OBJECTIVELabel.Visible='off';
            app.TextArea_3.Visible='on';
            app.APPARATUSLabel.Visible='on';
            app.UITable.Visible='on';
            app.THEORYLabel.Visible='off';
            app.TextArea_4.Visible='off';
        end

        % Value changed function: TextArea_2
        function TextArea_2ValueChanged(app, event)
            value = app.TextArea_2.Value;
            
        end

        % Callback function
        function RESETButtonPushed(app, event)
            app.TextArea_2.Visible='off';
            app.OBJECTIVELabel.Visible='off';
        end

        % Callback function
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end

        % Button pushed function: TheoryButton
        function TheoryButtonPushed(app, event)
            app.TextArea_2.Visible='off';
            app.OBJECTIVELabel.Visible='off';
            app.TextArea_3.Visible='off';
            app.APPARATUSLabel.Visible='off';
            app.UITable.Visible='off';
            app.THEORYLabel.Visible='on';
            app.TextArea_4.Visible='on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.VerificationOfTheveninsTheoremLabel = uilabel(app.UIFigure);
            app.VerificationOfTheveninsTheoremLabel.Text = 'Verification Of Thevenin''s Theorem';
            app.TextArea = uitextarea(app.UIFigure);
            app.TheoryButton = uibutton(app.UIFigure, 'push');
            app.TheoryButton.ButtonPushedFcn = createCallbackFcn(app, @TheoryButtonPushed, true);
            app.TheoryButton.Icon = 'Theoryicon.png';
            app.TheoryButton.Text = '      Theory     ';
            app.ProcedureButton = uibutton(app.UIFigure, 'push');
            app.ProcedureButton.Icon = 'procedureicon.png';
            app.ProcedureButton.Text = '    Procedure   ';
            app.SimulatorButton = uibutton(app.UIFigure, 'push');
            app.SimulatorButton.Icon = 'simulatoricon.png';
            app.SimulatorButton.Text = '      Simulator    ';
            app.QuestionsButton = uibutton(app.UIFigure, 'push');
            app.QuestionsButton.Icon = 'quizicon.png';
            app.QuestionsButton.Text = '     Questions    ';
            app.VideoLectureButton = uibutton(app.UIFigure, 'push');
            app.VideoLectureButton.Icon = 'Videolecturesicon.png';
            app.VideoLectureButton.Text = '   Video Lecture';
            app.ReferencesButton = uibutton(app.UIFigure, 'push');
            app.ReferencesButton.Icon = 'referencesicon.png';
            app.ReferencesButton.Text = '    References';
            app.OBJECTIVELabel = uilabel(app.UIFigure);
            app.OBJECTIVELabel.Visible = 'off';
            app.OBJECTIVELabel.Text = 'OBJECTIVE';
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.ValueChangedFcn = createCallbackFcn(app, @TextArea_2ValueChanged, true);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Visible = 'off';
            app.TextArea_2.Value = {'   To study and verify Thevenin’s theorem.'};
            app.ObjectiveButton = uibutton(app.UIFigure, 'push');
            app.ObjectiveButton.ButtonPushedFcn = createCallbackFcn(app, @ObjectiveButtonPushed, true);
            app.ObjectiveButton.Icon = 'Objective.png';
            app.ObjectiveButton.Text = '      Objective     ';
            app.ApparatusButton = uibutton(app.UIFigure, 'push');
            app.ApparatusButton.ButtonPushedFcn = createCallbackFcn(app, @ApparatusButtonPushed, true);
            app.ApparatusButton.Icon = 'Apparatus.png';
            app.ApparatusButton.Text = '    Apparatus   ';
            app.TextArea_3 = uitextarea(app.UIFigure);
            app.TextArea_3.Editable = 'off';
            app.TextArea_3.Visible = 'off';
            app.APPARATUSLabel = uilabel(app.UIFigure);
            app.APPARATUSLabel.Visible = 'off';
            app.APPARATUSLabel.Text = 'APPARATUS';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Sl. No.'; 'Name of the instrument & type'; 'Specification / make'; 'Quantity'};
            app.UITable.RowName = {};
            app.UITable.Visible = 'off';
            app.TextArea_4 = uitextarea(app.UIFigure);
            app.TextArea_4.Value = {'    In network analysis, many times it is necessary to determine the branch current through a specific branch. In such'; '    type of problem, solving total set of loop equations to find particular branch current is time consuming in case of '; '    complicated networks.The problem is simplified by using Thevenin’s theorem which states that: -'; ''; '             “A linear two-terminal circuit can be replaced by an equivalent circuit consisting of a voltage source V_TH  '; '               in series with a single impedance Z_eq across the two terminals of the load Z_L.”'; ''; '    When load impedance Z_L is removed, the open circuit voltage measured at the two relevant terminals is known as '; '    the V_TH. This voltage is also known as Thevenin''s equivalent voltage. With Z_L disconnected and all active sources '; '    replaced by their internal impedances, the Z_eq is the equivalent impedance of the given network as seen through '; '    the terminals where Z_(L )is connected. Independent voltage sources should be replaced by short circuit while '; '    independent current sources should be replaced by open circuit if the internal impedances are unknown.'; ''};
            app.THEORYLabel = uilabel(app.UIFigure);
            app.THEORYLabel.Text = 'THEORY';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app6

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end